classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        LoadFileTab                    matlab.ui.container.Tab
        LoadFileButton                 matlab.ui.control.Button
        PermuteButtonGroup             matlab.ui.container.ButtonGroup
        Button                         matlab.ui.control.ToggleButton
        Button_2                       matlab.ui.control.ToggleButton
        Button_3                       matlab.ui.control.ToggleButton
        SampleNameEditFieldLabel       matlab.ui.control.Label
        SampleNameEditField            matlab.ui.control.EditField
        BE                             matlab.ui.control.UIAxes
        Fermi                          matlab.ui.control.UIAxes
        FermiLevelEditFieldLabel       matlab.ui.control.Label
        FermiLevelEditField            matlab.ui.control.NumericEditField
        FileNameEditFieldLabel         matlab.ui.control.Label
        FileNameEditField              matlab.ui.control.EditField
        BindingEnergyeVEditFieldLabel  matlab.ui.control.Label
        BindingEnergyeVEditField       matlab.ui.control.NumericEditField
        ScanKyTab                      matlab.ui.container.Tab
        YPosSliderLabel                matlab.ui.control.Label
        YPosSlider                     matlab.ui.control.Slider
        KyCuts                         matlab.ui.control.UIAxes
        SaveButton_Ky                  matlab.ui.control.Button
        KyvalueEditFieldLabel          matlab.ui.control.Label
        KyvalueEditField               matlab.ui.control.NumericEditField
        KyFermi                        matlab.ui.control.UIAxes
        ScanKxTab                      matlab.ui.container.Tab
        KxCuts                         matlab.ui.control.UIAxes
        XPosSliderLabel                matlab.ui.control.Label
        XPosSlider                     matlab.ui.control.Slider
        KxFermi                        matlab.ui.control.UIAxes
        KxvalueEditFieldLabel          matlab.ui.control.Label
        KxvalueEditField               matlab.ui.control.NumericEditField
        SaveButton_Kx                  matlab.ui.control.Button
        ScanEnergyTab                  matlab.ui.container.Tab
        UIAxes2_2                      matlab.ui.control.UIAxes
        ZPosSliderLabel                matlab.ui.control.Label
        ZPosSlider                     matlab.ui.control.Slider
        EnergyLevelEditFieldLabel      matlab.ui.control.Label
        EnergyLevelEditField           matlab.ui.control.NumericEditField
        SaveButton_3                   matlab.ui.control.Button
        EnergyContourTab               matlab.ui.container.Tab
        EnergyContourPlotDerivativeButton  matlab.ui.control.Button
        EnergyContourPlotButton        matlab.ui.control.Button
        Contour1EditFieldLabel         matlab.ui.control.Label
        Contour1EditField              matlab.ui.control.NumericEditField
        Contour2EditFieldLabel         matlab.ui.control.Label
        Contour2EditField              matlab.ui.control.NumericEditField
        Contour3EditFieldLabel         matlab.ui.control.Label
        Contour3EditField              matlab.ui.control.NumericEditField
        Contour4EditFieldLabel         matlab.ui.control.Label
        Contour4EditField              matlab.ui.control.NumericEditField
        Contour5EditFieldLabel         matlab.ui.control.Label
        Contour5EditField              matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Callback function: FileNameEditField, LoadFileButton
        function LoadFileButtonPushed(app, event)
            global A ypos B E;
            E = 60;
            Setup();         
            [baseName, folder] = uigetfile('Data/*.ibw');
            SamplePath = fullfile(folder, baseName);
            [A,B] = LoadFile(SamplePath); 
            app.XPosSlider.Limits = [1,size(B,1)];
            app.YPosSlider.Limits = [1,size(B,3)];
            app.ZPosSlider.Limits = [1,size(B,2)];
            app.FermiLevelEditField.Limits = [1,size(B,2)];
            app.FermiLevelEditField.Value = ypos;
            setvariables();
            UpdateFermi(ypos,app.Fermi);
            app.FileNameEditField.Value = baseName;
        end

        % Value changed function: SampleNameEditField
        function SampleNameEditFieldValueChanged(app, event)
            global SampleName;
            value = app.SampleNameEditField.Value;
            SampleName = value;
        end

        % Value changed function: FermiLevelEditField
        function FermiLevelEditFieldValueChanged(app, event)
            global ypos eV Ymin Ylim Ymax Fermi_Level;
            value = app.FermiLevelEditField.Value;
            ypos = value;
            Fermi_Level = abs(Ymin) + ((ypos./Ylim)*(abs(Ymax) - abs(Ymin)));
            UpdateFermi(ypos,app.Fermi);
            app.BindingEnergyeVEditField.Value = eV(ypos) + 4.5;
        end

        % Value changed function: BindingEnergyeVEditField
        function BindingEnergyeVEditFieldValueChanged(app, event)
            value = app.BindingEnergyeVEditField.Value;
        end

        % Value changed function: YPosSlider
        function YPosSliderValueChanged(app, event)
            global ypos KY_Angle_Max KY_Angle_Min KX_Angle_Max KX_Angle_Min zpos Zlim xpos Xlim;
            value = round(app.YPosSlider.Value);
            app.KyvalueEditField.Value = value;
            GuiYCut(value,app.KyCuts)
            UpdateFermi(ypos,app.KyFermi)
            DKY = (KY_Angle_Max - KY_Angle_Min)*(value/Zlim) + KY_Angle_Min;
            axis([KX_Angle_Min KX_Angle_Max KY_Angle_Min KY_Angle_Max]);
            line(app.KyFermi,[KX_Angle_Min KX_Angle_Max],[DKY DKY], 'color', 'black', 'linewidth',2,'linestyle','--')
        end

        % Button pushed function: SaveButton_Ky
        function SaveButton_KyPushed(app, event)
            global SampleName;
            file = uiputfile(strcat(SampleName,'_KYCut.png'));
        end

        % Button pushed function: SaveButton_Kx
        function SaveButton_KxPushed(app, event)
            global SampleName;
            file = uiputfile(strcat(SampleName,'_KXCut.png'));
        end

        % Value changed function: XPosSlider
        function XPosSliderValueChanged(app, event)
            global ypos KY_Angle_Max KY_Angle_Min KX_Angle_Max KX_Angle_Min zpos Zlim xpos Xlim;
            value = round(app.XPosSlider.Value);
            app.KxvalueEditField.Value = value;
            GuiXCut(value,app.KxCuts)
            UpdateFermi(ypos,app.KxFermi)
            DKX = (KX_Angle_Max - KX_Angle_Min)*(value/Xlim) + KX_Angle_Min;
            line(app.KxFermi,[DKX DKX],[KY_Angle_Min KY_Angle_Max], 'color', 'black', 'linewidth', 2,'linestyle','--')
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LoadFileTab = uitab(app.TabGroup);
            app.LoadFileTab.Title = 'Load File';
            app.LoadFileButton = uibutton(app.LoadFileTab, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            app.PermuteButtonGroup = uibuttongroup(app.LoadFileTab);
            app.PermuteButtonGroup.Title = 'Permute';
            app.Button = uitogglebutton(app.PermuteButtonGroup);
            app.Button.Text = '123';
            app.Button.Value = true;
            app.Button_2 = uitogglebutton(app.PermuteButtonGroup);
            app.Button_2.Text = '321';
            app.Button_3 = uitogglebutton(app.PermuteButtonGroup);
            app.Button_3.Text = '213';
            app.SampleNameEditFieldLabel = uilabel(app.LoadFileTab);
            app.SampleNameEditFieldLabel.Text = 'Sample Name';
            app.SampleNameEditField = uieditfield(app.LoadFileTab, 'text');
            app.SampleNameEditField.ValueChangedFcn = createCallbackFcn(app, @SampleNameEditFieldValueChanged, true);
            app.SampleNameEditField.Value = 'Default';
            app.BE = uiaxes(app.LoadFileTab);
            title(app.BE, 'Binding Energy')
            xlabel(app.BE, 'Energy (eV)')
            ylabel(app.BE, 'Intensity (Arb. Units.)')
            app.Fermi = uiaxes(app.LoadFileTab);
            title(app.Fermi, 'Fermi Surface')
            xlabel(app.Fermi, 'X')
            ylabel(app.Fermi, 'Y')
            app.FermiLevelEditFieldLabel = uilabel(app.LoadFileTab);
            app.FermiLevelEditFieldLabel.Text = 'Fermi Level';
            app.FermiLevelEditField = uieditfield(app.LoadFileTab, 'numeric');
            app.FermiLevelEditField.ValueChangedFcn = createCallbackFcn(app, @FermiLevelEditFieldValueChanged, true);
            app.FileNameEditFieldLabel = uilabel(app.LoadFileTab);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.LoadFileTab, 'text');
            app.FileNameEditField.ValueChangedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.FileNameEditField.Editable = 'off';
            app.BindingEnergyeVEditFieldLabel = uilabel(app.LoadFileTab);
            app.BindingEnergyeVEditFieldLabel.Text = 'Binding Energy (eV)';
            app.BindingEnergyeVEditField = uieditfield(app.LoadFileTab, 'numeric');
            app.BindingEnergyeVEditField.ValueChangedFcn = createCallbackFcn(app, @BindingEnergyeVEditFieldValueChanged, true);
            app.BindingEnergyeVEditField.Editable = 'off';
            app.ScanKyTab = uitab(app.TabGroup);
            app.ScanKyTab.Title = 'Scan Ky ';
            app.YPosSliderLabel = uilabel(app.ScanKyTab);
            app.YPosSliderLabel.Text = 'Y Pos';
            app.YPosSlider = uislider(app.ScanKyTab);
            app.YPosSlider.ValueChangedFcn = createCallbackFcn(app, @YPosSliderValueChanged, true);
            app.KyCuts = uiaxes(app.ScanKyTab);
            title(app.KyCuts, 'Ky Cuts')
            xlabel(app.KyCuts, 'X')
            ylabel(app.KyCuts, 'Y')
            app.SaveButton_Ky = uibutton(app.ScanKyTab, 'push');
            app.SaveButton_Ky.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_KyPushed, true);
            app.SaveButton_Ky.Text = 'Save';
            app.KyvalueEditFieldLabel = uilabel(app.ScanKyTab);
            app.KyvalueEditFieldLabel.Text = 'Ky value';
            app.KyvalueEditField = uieditfield(app.ScanKyTab, 'numeric');
            app.KyvalueEditField.Editable = 'off';
            app.KyFermi = uiaxes(app.ScanKyTab);
            title(app.KyFermi, 'Fermi')
            xlabel(app.KyFermi, 'X')
            ylabel(app.KyFermi, 'Y')
            app.ScanKxTab = uitab(app.TabGroup);
            app.ScanKxTab.Title = 'Scan Kx';
            app.KxCuts = uiaxes(app.ScanKxTab);
            title(app.KxCuts, 'Kx Cut')
            xlabel(app.KxCuts, 'X')
            ylabel(app.KxCuts, 'Y')
            app.XPosSliderLabel = uilabel(app.ScanKxTab);
            app.XPosSliderLabel.Text = 'X Pos';
            app.XPosSlider = uislider(app.ScanKxTab);
            app.XPosSlider.ValueChangedFcn = createCallbackFcn(app, @XPosSliderValueChanged, true);
            app.KxFermi = uiaxes(app.ScanKxTab);
            title(app.KxFermi, 'Fermi')
            xlabel(app.KxFermi, 'X')
            ylabel(app.KxFermi, 'Y')
            app.KxvalueEditFieldLabel = uilabel(app.ScanKxTab);
            app.KxvalueEditFieldLabel.Text = 'Kx value';
            app.KxvalueEditField = uieditfield(app.ScanKxTab, 'numeric');
            app.KxvalueEditField.Editable = 'off';
            app.SaveButton_Kx = uibutton(app.ScanKxTab, 'push');
            app.SaveButton_Kx.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_KxPushed, true);
            app.SaveButton_Kx.Text = 'Save';
            app.ScanEnergyTab = uitab(app.TabGroup);
            app.ScanEnergyTab.Title = 'Scan Energy';
            app.UIAxes2_2 = uiaxes(app.ScanEnergyTab);
            title(app.UIAxes2_2, 'Energy Level')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            app.ZPosSliderLabel = uilabel(app.ScanEnergyTab);
            app.ZPosSliderLabel.Text = 'Z Pos';
            app.ZPosSlider = uislider(app.ScanEnergyTab);
            app.EnergyLevelEditFieldLabel = uilabel(app.ScanEnergyTab);
            app.EnergyLevelEditFieldLabel.Text = 'Energy Level';
            app.EnergyLevelEditField = uieditfield(app.ScanEnergyTab, 'numeric');
            app.SaveButton_3 = uibutton(app.ScanEnergyTab, 'push');
            app.SaveButton_3.Text = 'Save';
            app.EnergyContourTab = uitab(app.TabGroup);
            app.EnergyContourTab.Title = 'Energy Contour';
            app.EnergyContourPlotDerivativeButton = uibutton(app.EnergyContourTab, 'push');
            app.EnergyContourPlotDerivativeButton.Text = 'Energy Contour Plot + Derivative';
            app.EnergyContourPlotButton = uibutton(app.EnergyContourTab, 'push');
            app.EnergyContourPlotButton.Text = 'Energy Contour Plot';
            app.Contour1EditFieldLabel = uilabel(app.EnergyContourTab);
            app.Contour1EditFieldLabel.Text = 'Contour 1';
            app.Contour1EditField = uieditfield(app.EnergyContourTab, 'numeric');
            app.Contour1EditField.Value = 50;
            app.Contour2EditFieldLabel = uilabel(app.EnergyContourTab);
            app.Contour2EditFieldLabel.Text = 'Contour 2';
            app.Contour2EditField = uieditfield(app.EnergyContourTab, 'numeric');
            app.Contour2EditField.Value = 100;
            app.Contour3EditFieldLabel = uilabel(app.EnergyContourTab);
            app.Contour3EditFieldLabel.Text = 'Contour 3';
            app.Contour3EditField = uieditfield(app.EnergyContourTab, 'numeric');
            app.Contour3EditField.Value = 150;
            app.Contour4EditFieldLabel = uilabel(app.EnergyContourTab);
            app.Contour4EditFieldLabel.Text = 'Contour 4';
            app.Contour4EditField = uieditfield(app.EnergyContourTab, 'numeric');
            app.Contour4EditField.Value = 200;
            app.Contour5EditFieldLabel = uilabel(app.EnergyContourTab);
            app.Contour5EditFieldLabel.Text = 'Contour 5';
            app.Contour5EditField = uieditfield(app.EnergyContourTab, 'numeric');
            app.Contour5EditField.Value = 250;
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end